classdef FluidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        TabWaterNavier           matlab.ui.container.Tab
        ButtonPanel              matlab.ui.container.Panel
        TabGroupButtons          matlab.ui.container.TabGroup
        WaterSettings            matlab.ui.container.Tab
        GridLayout               matlab.ui.container.GridLayout
        PLAYButton               matlab.ui.control.Button
        TimeEditField            matlab.ui.control.EditField
        TimeEditFieldLabel       matlab.ui.control.Label
        VISCOSITYEditField       matlab.ui.control.EditField
        VISCOSITYEditFieldLabel  matlab.ui.control.Label
        DIFFEditField            matlab.ui.control.EditField
        DIFFLabel                matlab.ui.control.Label
        DTEditField              matlab.ui.control.EditField
        DTLabel                  matlab.ui.control.Label
        SIZEEditField            matlab.ui.control.EditField
        SIZELabel                matlab.ui.control.Label
        FADEONCheckBox           matlab.ui.control.CheckBox
        REDITButton              matlab.ui.control.Button
        C_ECell                  matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        PyEditField              matlab.ui.control.EditField
        PyEditFieldLabel         matlab.ui.control.Label
        PxEditField              matlab.ui.control.EditField
        PxEditFieldLabel         matlab.ui.control.Label
        VyEditField              matlab.ui.control.EditField
        VyEditFieldLabel         matlab.ui.control.Label
        VxEditField              matlab.ui.control.EditField
        VxEditFieldLabel         matlab.ui.control.Label
        R_ECellsButton           matlab.ui.control.Button
        C_EBlocks                matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        SyEditField              matlab.ui.control.EditField
        SyEditFieldLabel         matlab.ui.control.Label
        SxEditField              matlab.ui.control.EditField
        SxEditFieldLabel         matlab.ui.control.Label
        AngleEditField           matlab.ui.control.EditField
        AngleEditFieldLabel      matlab.ui.control.Label
        PyEditField_2            matlab.ui.control.EditField
        PyEditField_2Label       matlab.ui.control.Label
        PxEditField_2            matlab.ui.control.EditField
        PxEditField_2Label       matlab.ui.control.Label
        R_EBlocksButton          matlab.ui.control.Button
        GraphPanel               matlab.ui.container.Panel
        Graph                    matlab.ui.control.UIAxes
        WaterCells               matlab.ui.container.Tab
        ButtonPanel_2            matlab.ui.container.Panel
        TabGroupButtons_2        matlab.ui.container.TabGroup
        ReMakeGrid               matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        TimeEditField_2          matlab.ui.control.EditField
        TimeEditField_2Label     matlab.ui.control.Label
        NumTonesEditField        matlab.ui.control.EditField
        NumTonesEditFieldLabel   matlab.ui.control.Label
        NEditField               matlab.ui.control.EditField
        NEditFieldLabel          matlab.ui.control.Label
        PlayButton               matlab.ui.control.Button
        CreateButton             matlab.ui.control.Button
        C_ECell_2                matlab.ui.container.Tab
        GridLayout2_2            matlab.ui.container.GridLayout
        DensidadeEditField       matlab.ui.control.EditField
        DensidadeEditFieldLabel  matlab.ui.control.Label
        PxEditField_6            matlab.ui.control.EditField
        PxEditField_6Label       matlab.ui.control.Label
        PyEditField_5            matlab.ui.control.EditField
        PyEditField_5Label       matlab.ui.control.Label
        R_ECellsButton_2         matlab.ui.control.Button
        C_EBlocks_2              matlab.ui.container.Tab
        GridLayout3_2            matlab.ui.container.GridLayout
        PxEditField_5            matlab.ui.control.EditField
        PxEditField_5Label       matlab.ui.control.Label
        PyEditField_4            matlab.ui.control.EditField
        PyEditField_4Label       matlab.ui.control.Label
        R_EBlocksButton_2        matlab.ui.control.Button
        GraphPanel_2             matlab.ui.container.Panel
        Graph_2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Navier
        N % Tamanho do grid WaterNavier
        fluid % Fluid Navier
        fluidCreate=0; % Indica se o fluido navier foi criado
        time=0; % tempo do loop
        MatrixCube=[]


        %% Celular automata
        Nc % Tamanho do grid automata
        COLOR_SCALE=[]
        coloredImage=[]
        numTones=0
        grid=[]
        grid_direction=[]
        thresholds=[]
    end
    
    methods (Access = private)
        
        function draw(app)
            image=[];
            if size(app.MatrixCube)==size(app.fluid.density)
                image = app.fluid.density+app.MatrixCube;
            else
                image = app.fluid.density;

            end
            imagesc(app.Graph,image);
            colormap(app.Graph,gray);
            
            
        end
        
        function  drawGridCell(app)
             for i = 1:app.Nc
                for j = 1:app.Nc
                    color = app.shade_of_white(app.grid(i, j), app.COLOR_SCALE,app.thresholds);
                    app.coloredImage(i, j, :) = color;
                end
            end
                
            imagesc(app.Graph_2,app.coloredImage);
            
            
        end
        
        function color = shade_of_white(app,value, GRAY_SCALE,thresholds)
            color = GRAY_SCALE(end, :);
            for i = 1:length(thresholds)
                if value ==0
                    color=[255,255,255];
                elseif value ==-1
                    color = [0,255,0];
                elseif value >1
                    color = [0,0,0];
                elseif value < thresholds(i)
                    color = GRAY_SCALE(i, :);
                    break;
                end
            end
        end
            
    
        function [grid, grid_direction] = update(app,grid, grid_direction)
            div = 0.25;
            [rows, cols] = size(grid);
            
            %Limpeza
            for y = 1:rows
                for x = 1:cols
                    if grid(y, x) <= 0
                        grid_direction{y, x} = [0,0];
                    end
                end
            end
        
            %Gravidade
            for y = rows:-1:1
                for x = 1:cols
                    if y < rows && grid(y, x) > 0 && grid(y+1, x) < 1 && grid(y+1, x) ~= -1
                        if grid(y, x) == grid(y+1, x)
                            value = grid(y, x);
                        else
                            value = min(grid(y, x) - grid(y+1, x), div);
                        end
                        grid(y+1, x) = grid(y+1, x) + value;
                        grid(y, x) = grid(y, x) - value;
                        grid_direction{y+1, x} = [1, 0]; % Para baixo
                    end
                end
            end
            
            %Divisao esq/dir
            for y = 1:rows
                for x = 1:cols-1
        
                    a = randi([-1, 1]);
                    if x==1
                        a=1;
                    end
                    if grid(y, x+a) < 1 && grid(y, x+a) >= 0 && grid(y, x) > 0 && ((y < rows && grid(y+1, x) ~= 0) || y == rows) && grid(y, x+a) ~= -1
                        value = min((grid(y, x) - grid(y, x+a)), div);
                        grid(y, x+a) = grid(y, x+a) + value;
                        grid(y, x) = grid(y, x) - value;
                        if a > 0
                            grid_direction{y, x} = [0, 1]; % Para direita
        
                        else
                            grid_direction{y, x} = [0, -1]; % Para esquerda
        
                        end
                    elseif grid(y, x+a) >= 0 && grid(y, x) > 1 && ((y < rows && grid(y+1, x) ~= 0) || y == rows) && grid(y, x+a) ~= -1
                        value = min((grid(y, x) - grid(y, x+a)), div);
                        grid(y, x+a) = grid(y, x+a) + value;
                        grid(y, x) = grid(y, x) - value;
                        if a > 0
                            grid_direction{y, x} = [0, 1]; % Para direita
                        else
                            grid_direction{y, x} = [0, -1]; % Para esquerda
                        end
                    end
                end
            end
        
            %Pressao
            for y = 1:rows-1
                for x = 2:cols-1
                    if (grid(y+1, x-1) >= 0 || grid(y+1, x-1) < 0) && (grid(y+1, x+1) >= 0 || grid(y+1, x+1) < 0) && grid(y+1, x) > 1 && grid(y, x) >= 0
                        value = min((grid(y, x) - grid(y+1, x)), div);
                        grid(y+1, x) = grid(y+1, x) + value;
                        grid(y, x) = grid(y, x) - value;
                        grid_direction{y+1, x} = [-1, 0]; % Para cima;
                    end
                end
            end


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: REDITButton
        function REDITButtonPushed(app, event)
            visc = str2double(app.VISCOSITYEditField.Value);
            diff =str2double(app.DIFFEditField.Value);
            dt = str2double(app.DTEditField.Value);
            app.N = str2double(app.SIZEEditField.Value);
            app.time = str2double(app.TimeEditField.Value);
            fadeon = app.FADEONCheckBox.Value;
            app.fluid = Fluid(app.N,dt, diff, visc);
            app.fluidCreate=1;
            app.draw()
            
        end

        % Value changed function: SIZEEditField
        function SIZEEditFieldValueChanged(app, event)
            app.N = str2double(app.SIZEEditField.Value);
        end

        % Button pushed function: R_ECellsButton
        function R_ECellsButtonPushed(app, event)
            if app.fluidCreate==1
            Vx = str2double(app.VxEditField.Value);
            Vy = str2double(app.VyEditField.Value);
            Px = str2double(app.PxEditField.Value);
            Py = str2double(app.PyEditField.Value);
            if Px>0 && Py>0
            app.fluid.addDensity(Px, Py, floor(50+rand*100));
            app.fluid.addVelocity(Px, Py, Vx, Vy);
            app.draw()
            end

            end
            
            
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            k=0;
            for t=0:app.time
	            app.fluid.step();
                app.draw()
                drawnow
            
            end
        end

        % Button pushed function: R_EBlocksButton
        function R_EBlocksButtonPushed(app, event)
            app.fluidCreate
            if app.fluidCreate==1
                Px = str2double(app.PxEditField_2.Value);
                Py =str2double(app.PyEditField_2.Value);
                if Px>0 && Py>0
                angle =str2double(app.AngleEditField.Value);
                Sx =str2double(app.SxEditField.Value);
                Sy =str2double(app.SyEditField.Value);
                cube = Square([Px,Py],Sx,Sy,angle);
                app.MatrixCube = 500*cube.draw(app.N); 
                app.fluid.setBlock(app.MatrixCube);
                app.draw()
                end
            end
        end

        % Button pushed function: R_ECellsButton_2
        function R_ECellsButton_2Pushed(app, event)
            Px=str2double(app.PyEditField_5.Value);
            Py=str2double(app.PxEditField_6.Value);
            if Px>0 && Py>0
            densidade=str2double(app.DensidadeEditField.Value);
            app.grid(Px,Py)=densidade;
            app.drawGridCell();
            end
            
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.Nc = str2double(app.NEditField.Value);
    
            app.grid = zeros(app.Nc);
            app.grid_direction = cell(app.Nc);
            app.thresholds = linspace(0,1,str2double(app.NumTonesEditField.Value));
            A= size(app.thresholds);
            
            app.numTones = A(2);
            app.COLOR_SCALE = uint8([zeros(app.numTones, 2), linspace(255, 160, app.numTones)']);
            app.COLOR_SCALE(1, :) = [255, 255, 255];
            app.COLOR_SCALE = reshape(app.COLOR_SCALE, [app.numTones, 1, 3]);
            app.coloredImage = uint8(zeros(app.Nc, app.Nc, 3));
        
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            app.drawGridCell()


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                
            tt = str2double(app.TimeEditField_2.Value);
            for i=0:tt
                
                hold(app.Graph_2,"off");
                [app.grid,app.grid_direction]=app.update(app.grid,app.grid_direction);
                app.drawGridCell()
                hold(app.Graph_2,"on");
                U = zeros(size(app.grid)); % Componente vertical das setas
                V = zeros(size(app.grid)); % Componente horizontal das setas
                for y = 1:app.Nc
                    for x = 1:app.Nc
                        U(y, x) = app.grid_direction{y, x}(1);
                        V(y, x) = app.grid_direction{y, x}(2);
                    end
                end
                quiver(app.Graph_2,1:app.Nc, 1:app.Nc, V, U, 'AutoScale', 'off');
                drawnow;
            end


        end

        % Button pushed function: R_EBlocksButton_2
        function R_EBlocksButton_2Pushed(app, event)
            Px=str2double(app.PyEditField_4.Value);
            Py=str2double(app.PxEditField_5.Value);
            if Px>0 && Py>0
            app.grid(Px,Py)=-1;
            app.drawGridCell();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabWaterNavier = uitab(app.TabGroup);
            app.TabWaterNavier.Title = 'WaterNavier';
            app.GraphPanel = uipanel(app.TabWaterNavier);
            app.Graph = uiaxes(app.GraphPanel);
            app.Graph.Visible = 'off';
            app.ButtonPanel = uipanel(app.TabWaterNavier);
            app.TabGroupButtons = uitabgroup(app.ButtonPanel);
            app.WaterSettings = uitab(app.TabGroupButtons);
            app.WaterSettings.Title = 'WaterSettings';
            app.GridLayout = uigridlayout(app.WaterSettings);
            app.REDITButton = uibutton(app.GridLayout, 'push');
            app.REDITButton.ButtonPushedFcn = createCallbackFcn(app, @REDITButtonPushed, true);
            app.REDITButton.Text = 'REDIT';
            app.FADEONCheckBox = uicheckbox(app.GridLayout);
            app.FADEONCheckBox.Text = 'FADEON';
            app.SIZELabel = uilabel(app.GridLayout);
            app.SIZELabel.Text = 'SIZE  ';
            app.SIZEEditField = uieditfield(app.GridLayout, 'text');
            app.SIZEEditField.InputType = 'digits';
            app.SIZEEditField.ValueChangedFcn = createCallbackFcn(app, @SIZEEditFieldValueChanged, true);
            app.SIZEEditField.Value = '64';
            app.DTLabel = uilabel(app.GridLayout);
            app.DTLabel.Text = 'DT  ';
            app.DTEditField = uieditfield(app.GridLayout, 'text');
            app.DTEditField.Value = '1.5';
            app.DIFFLabel = uilabel(app.GridLayout);
            app.DIFFLabel.Text = 'DIFF ';
            app.DIFFEditField = uieditfield(app.GridLayout, 'text');
            app.DIFFEditField.Value = '0';
            app.VISCOSITYEditFieldLabel = uilabel(app.GridLayout);
            app.VISCOSITYEditFieldLabel.Text = 'VISCOSITY';
            app.VISCOSITYEditField = uieditfield(app.GridLayout, 'text');
            app.VISCOSITYEditField.Value = '0';
            app.TimeEditFieldLabel = uilabel(app.GridLayout);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.GridLayout, 'text');
            app.TimeEditField.InputType = 'digits';
            app.TimeEditField.Value = '100';
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.C_ECell = uitab(app.TabGroupButtons);
            app.C_ECell.Title = 'C_ECell';
            app.GridLayout2 = uigridlayout(app.C_ECell);
            app.R_ECellsButton = uibutton(app.GridLayout2, 'push');
            app.R_ECellsButton.ButtonPushedFcn = createCallbackFcn(app, @R_ECellsButtonPushed, true);
            app.R_ECellsButton.Text = 'R_ECells';
            app.VxEditFieldLabel = uilabel(app.GridLayout2);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.GridLayout2, 'text');
            app.VxEditField.InputType = 'digits';
            app.VxEditField.Value = '0';
            app.VyEditFieldLabel = uilabel(app.GridLayout2);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.GridLayout2, 'text');
            app.VyEditField.InputType = 'digits';
            app.VyEditField.Value = '0';
            app.PxEditFieldLabel = uilabel(app.GridLayout2);
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.GridLayout2, 'text');
            app.PxEditField.CharacterLimits = [1 Inf];
            app.PxEditField.InputType = 'digits';
            app.PxEditField.Value = '1';
            app.PyEditFieldLabel = uilabel(app.GridLayout2);
            app.PyEditFieldLabel.Text = 'Py';
            app.PyEditField = uieditfield(app.GridLayout2, 'text');
            app.PyEditField.CharacterLimits = [1 Inf];
            app.PyEditField.InputType = 'digits';
            app.PyEditField.Value = '1';
            app.C_EBlocks = uitab(app.TabGroupButtons);
            app.C_EBlocks.Title = 'C_EBlocks';
            app.GridLayout3 = uigridlayout(app.C_EBlocks);
            app.R_EBlocksButton = uibutton(app.GridLayout3, 'push');
            app.R_EBlocksButton.ButtonPushedFcn = createCallbackFcn(app, @R_EBlocksButtonPushed, true);
            app.R_EBlocksButton.Text = 'R_EBlocks';
            app.PxEditField_2Label = uilabel(app.GridLayout3);
            app.PxEditField_2Label.Text = 'Px';
            app.PxEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.PxEditField_2.CharacterLimits = [1 Inf];
            app.PxEditField_2.InputType = 'digits';
            app.PxEditField_2.Value = '1';
            app.PyEditField_2Label = uilabel(app.GridLayout3);
            app.PyEditField_2Label.Text = 'Py';
            app.PyEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.PyEditField_2.CharacterLimits = [1 Inf];
            app.PyEditField_2.InputType = 'digits';
            app.PyEditField_2.Value = '1';
            app.AngleEditFieldLabel = uilabel(app.GridLayout3);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.GridLayout3, 'text');
            app.AngleEditField.InputType = 'digits';
            app.AngleEditField.Value = '0';
            app.SxEditFieldLabel = uilabel(app.GridLayout3);
            app.SxEditFieldLabel.Text = 'Sx';
            app.SxEditField = uieditfield(app.GridLayout3, 'text');
            app.SxEditField.InputType = 'digits';
            app.SxEditField.Value = '0';
            app.SyEditFieldLabel = uilabel(app.GridLayout3);
            app.SyEditFieldLabel.Text = 'Sy';
            app.SyEditField = uieditfield(app.GridLayout3, 'text');
            app.SyEditField.InputType = 'digits';
            app.SyEditField.Value = '0';
            app.WaterCells = uitab(app.TabGroup);
            app.WaterCells.Title = 'WaterCells';
            app.GraphPanel_2 = uipanel(app.WaterCells);
            app.Graph_2 = uiaxes(app.GraphPanel_2);
            app.Graph_2.Visible = 'off';
            app.ButtonPanel_2 = uipanel(app.WaterCells);
            app.TabGroupButtons_2 = uitabgroup(app.ButtonPanel_2);
            app.ReMakeGrid = uitab(app.TabGroupButtons_2);
            app.ReMakeGrid.Title = 'ReMakeGrid';
            app.GridLayout4 = uigridlayout(app.ReMakeGrid);
            app.CreateButton = uibutton(app.GridLayout4, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.PlayButton = uibutton(app.GridLayout4, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.NEditFieldLabel = uilabel(app.GridLayout4);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.GridLayout4, 'text');
            app.NEditField.Value = '32';
            app.NumTonesEditFieldLabel = uilabel(app.GridLayout4);
            app.NumTonesEditFieldLabel.Text = 'Num Tones';
            app.NumTonesEditField = uieditfield(app.GridLayout4, 'text');
            app.NumTonesEditField.Value = '10';
            app.TimeEditField_2Label = uilabel(app.GridLayout4);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.GridLayout4, 'text');
            app.TimeEditField_2.Value = '1000';
            app.C_ECell_2 = uitab(app.TabGroupButtons_2);
            app.C_ECell_2.Title = 'C_ECell';
            app.GridLayout2_2 = uigridlayout(app.C_ECell_2);
            app.R_ECellsButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.R_ECellsButton_2.ButtonPushedFcn = createCallbackFcn(app, @R_ECellsButton_2Pushed, true);
            app.R_ECellsButton_2.Text = 'R_ECells';
            app.PyEditField_5Label = uilabel(app.GridLayout2_2);
            app.PyEditField_5Label.Text = 'Py';
            app.PyEditField_5 = uieditfield(app.GridLayout2_2, 'text');
            app.PyEditField_5.Value = '1';
            app.PxEditField_6Label = uilabel(app.GridLayout2_2);
            app.PxEditField_6Label.Text = 'Px';
            app.PxEditField_6 = uieditfield(app.GridLayout2_2, 'text');
            app.PxEditField_6.Value = '1';
            app.DensidadeEditFieldLabel = uilabel(app.GridLayout2_2);
            app.DensidadeEditFieldLabel.Text = 'Densidade';
            app.DensidadeEditField = uieditfield(app.GridLayout2_2, 'text');
            app.DensidadeEditField.Value = '0';
            app.C_EBlocks_2 = uitab(app.TabGroupButtons_2);
            app.C_EBlocks_2.Title = 'C_EBlocks';
            app.GridLayout3_2 = uigridlayout(app.C_EBlocks_2);
            app.R_EBlocksButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.R_EBlocksButton_2.ButtonPushedFcn = createCallbackFcn(app, @R_EBlocksButton_2Pushed, true);
            app.R_EBlocksButton_2.Text = 'R_EBlocks';
            app.PyEditField_4Label = uilabel(app.GridLayout3_2);
            app.PyEditField_4Label.Text = 'Py';
            app.PyEditField_4 = uieditfield(app.GridLayout3_2, 'text');
            app.PyEditField_4.Value = '1';
            app.PxEditField_5Label = uilabel(app.GridLayout3_2);
            app.PxEditField_5Label.Text = 'Px';
            app.PxEditField_5 = uieditfield(app.GridLayout3_2, 'text');
            app.PxEditField_5.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end