classdef FluidAutomata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout7            matlab.ui.container.GridLayout
        ButtonPanel            matlab.ui.container.Panel
        TabGroupButtons        matlab.ui.container.TabGroup
        ReMakeGrid             matlab.ui.container.Tab
        GridLayout4            matlab.ui.container.GridLayout
        VetoresSwitch          matlab.ui.control.Switch
        VetoresSwitchLabel     matlab.ui.control.Label
        DRAWDropDown           matlab.ui.control.DropDown
        DRAWDropDownLabel      matlab.ui.control.Label
        CRIAAOLabel            matlab.ui.control.Label
        NumerodeTonsLabel      matlab.ui.control.Label
        SizeLabel              matlab.ui.control.Label
        TempoLabel             matlab.ui.control.Label
        TimeEditField          matlab.ui.control.EditField
        NumTonesEditField      matlab.ui.control.EditField
        NEditField             matlab.ui.control.EditField
        PlayButton             matlab.ui.control.Button
        CreateButton           matlab.ui.control.Button
        C_ECell                matlab.ui.container.Tab
        GridLayout5            matlab.ui.container.GridLayout
        AdicionarLiquidoLabel  matlab.ui.control.Label
        AdicionarblocoLabel    matlab.ui.control.Label
        RemoverLabel           matlab.ui.control.Label
        PxLabel_3              matlab.ui.control.Label
        PyLabel_3              matlab.ui.control.Label
        PxLabel_2              matlab.ui.control.Label
        PyLabel_2              matlab.ui.control.Label
        DensidadeLabel         matlab.ui.control.Label
        PxLabel                matlab.ui.control.Label
        PyLabel                matlab.ui.control.Label
        PxEditField_5          matlab.ui.control.EditField
        PyEditField_5          matlab.ui.control.EditField
        RemoveButton           matlab.ui.control.Button
        PxEditField_4          matlab.ui.control.EditField
        PyEditField_4          matlab.ui.control.EditField
        R_EBlocksButton        matlab.ui.control.Button
        DensidadeEditField     matlab.ui.control.EditField
        PxEditField_3          matlab.ui.control.EditField
        PyEditField_3          matlab.ui.control.EditField
        R_ECellsButton         matlab.ui.control.Button
        C_EBlocks              matlab.ui.container.Tab
        GridLayout6            matlab.ui.container.GridLayout
        TypeDropDown           matlab.ui.control.DropDown
        TypeDropDownLabel      matlab.ui.control.Label
        tamanhoEditField       matlab.ui.control.EditField
        tamanhoEditFieldLabel  matlab.ui.control.Label
        PyC_Eblock             matlab.ui.control.EditField
        PxLabel_4              matlab.ui.control.Label
        PxC_Eblock             matlab.ui.control.EditField
        PyEditField_6Label     matlab.ui.control.Label
        CreateBlockButton      matlab.ui.control.Button
        Switch                 matlab.ui.control.Switch
        C_EBlocks_2            matlab.ui.container.Tab
        GridLayout6_2          matlab.ui.container.GridLayout
        Modelo5Button          matlab.ui.control.Button
        Modelo4Button          matlab.ui.control.Button
        Modelo3Button          matlab.ui.control.Button
        Modelo2Button          matlab.ui.control.Button
        Modelo1Button          matlab.ui.control.Button
        GraphPanel             matlab.ui.container.Panel
        RogerioPanel           matlab.ui.container.Panel
        RogerioFalas           matlab.ui.control.Label
        Button                 matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        Graph                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Celular automata
        Nc % Tamanho do grid automata
        COLOR_SCALE=[]
        coloredImage=[]
        numTones=0
        grid=[]
        grid_direction=[]
        thresholds=[]
        frasesDoRogerio = {
            'Ajustando a densidade dos fluidos...';
            'Cuidado, não ultrapasse os limites do grid!';
            'Fluidos em movimento. Vamos observar a dinâmica!';
            'Simulação em progresso...';
            'Experimente alterar o número de tons para uma visualização diferente.';
            'Tudo pronto para a próxima simulação, clique em Play!';
            'Verifique os valores de densidade antes de adicionar novos blocos.';
            'Que tal experimentar uma nova configuração de grid?';
            'Precisão é chave! Certifique-se de suas entradas.';
            'A simulação de fluidos pode revelar padrões interessantes!';
            'Adicionando um novo bloco ao grid...';
            'Removendo um elemento... Observe a mudança na distribuição de fluidos.';
            'A gravidade está fazendo seu trabalho no grid.';
            'Vamos explorar diferentes configurações para entender melhor os fluidos.';
            'Uma pequena mudança pode fazer uma grande diferença nos resultados!';
            'O grid está se atualizando, observe com atenção.';
            'Pressão e gravidade são cruciais nesse tipo de simulação.';
            'Teste diferentes formas e veja como os fluidos reagem.';
            'Está gostando da visualização? Isso é só o começo!';
            'As bordas do grid são essenciais para manter o fluido dentro.';
            'A densidade do fluido influencia diretamente o movimento.';
            'Vamos ver como a mudança na temperatura afeta os fluidos!';
            'Cuidado com os obstáculos no caminho do fluido.';
            'A precisão nas coordenadas é vital para uma simulação correta.';
            'A visualização dos vetores pode ajudar a entender a direção do fluido.';
            'Simulações repetidas podem mostrar padrões ocultos.';
            'Não esqueça de salvar seus resultados!';
            'As células vazias podem ser preenchidas estrategicamente.';
            'O ajuste de parâmetros pode levar a descobertas interessantes.';
            'Vamos adicionar um pouco de turbulência ao fluido!';
            'A cor das células pode indicar diferentes propriedades do fluido.'
        };

    end
    
    methods (Access = private)

        function  drawGridCell(app)
             type = app.DRAWDropDown.Value;
             for i = 1:app.Nc
                for j = 1:app.Nc
                    color = app.shade_of_white(app.grid(i, j), app.COLOR_SCALE,app.thresholds);
                    app.coloredImage(i, j, :) = color;
                end
             end
            if type =="imagesc"
                imagesc(app.Graph,app.coloredImage);
            elseif type =="contourf"
                    contourf(app.Graph,app.coloredImage(:,:,1));
            elseif type =="pcolor"
                    pcolor(app.Graph,app.coloredImage(:,:,1));
            else
                contour(app.Graph,app.coloredImage(:,:,1));
            end
            
        end
        
    
        function [grid, grid_direction] = update(app,grid, grid_direction)
            div = 0.25;
            [rows, cols] = size(grid);
            
            %Limpeza
            for y = 1:rows
                for x = 1:cols
                    if grid(y, x) <= 0
                        grid_direction{y, x} = [0,0];
                    end
                end
            end
        
            %Gravidade
            for y = rows:-1:1
                for x = 1:cols
                    if y < rows && grid(y, x) > 0 && grid(y+1, x) < 1 && grid(y+1, x) ~= -1
                        if grid(y, x) == grid(y+1, x)
                            value = grid(y, x);
                        else
                            value = min(grid(y, x) - grid(y+1, x), div);
                        end
                        grid(y+1, x) = grid(y+1, x) + value;
                        grid(y, x) = grid(y, x) - value;
                        grid_direction{y+1, x} = [1, 0]; % Para baixo
                    end
                end
            end
            
            %Divisao esq/dir
            for y = 1:rows
                for x = 1:cols-1
        
                    a = randi([-1, 1]);
                    if x==1
                        a=1;
                    end
                    if grid(y, x+a) < 1 && grid(y, x+a) >= 0 && grid(y, x) > 0 && ((y < rows && grid(y+1, x) ~= 0) || y == rows) && grid(y, x+a) ~= -1
                        value = min((grid(y, x) - grid(y, x+a)), div);
                        grid(y, x+a) = grid(y, x+a) + value;
                        grid(y, x) = grid(y, x) - value;
                        if a > 0
                            grid_direction{y, x} = [0, 1]; % Para direita
        
                        else
                            grid_direction{y, x} = [0, -1]; % Para esquerda
        
                        end
                    elseif grid(y, x+a) >= 0 && grid(y, x) > 1 && ((y < rows && grid(y+1, x) ~= 0) || y == rows) && grid(y, x+a) ~= -1
                        value = min((grid(y, x) - grid(y, x+a)), div);
                        grid(y, x+a) = grid(y, x+a) + value;
                        grid(y, x) = grid(y, x) - value;
                        if a > 0
                            grid_direction{y, x} = [0, 1]; % Para direita
                        else
                            grid_direction{y, x} = [0, -1]; % Para esquerda
                        end
                    end
                end
            end
        
            %Pressao
            for y = 1:rows-1
                for x = 2:cols-1
                    if (grid(y+1, x-1) >= 0 || grid(y+1, x-1) < 0) && (grid(y+1, x+1) >= 0 || grid(y+1, x+1) < 0) && grid(y+1, x) > 1 && grid(y, x) >= 0
                        value = min((grid(y, x) - grid(y+1, x)), div);
                        grid(y+1, x) = grid(y+1, x) + value;
                        grid(y, x) = grid(y, x) - value;
                        grid_direction{y+1, x} = [-1, 0]; % Para cima;
                    end
                end
            end


        end
    
    
        function color = shade_of_white(app,value, GRAY_SCALE,thresholds)
            color = GRAY_SCALE(end, :);
            for i = 1:length(thresholds)
                if value ==0
                    color=[255,255,255];
                elseif value ==-1
                    color = [0,255,0];
                elseif value >1
                    color = [0,0,0];
                elseif value < thresholds(i)
                    color = GRAY_SCALE(i, :);
                    break;
                end
            end
        end


        function createCircle(app,px,py,r,d)
            [X, Y] = meshgrid(1:size(app.grid, 2), 1:size(app.grid, 1));
            distancia_centro = sqrt((X - px).^2 + (Y - py).^2);
            app.grid(distancia_centro <= r) = d;
            app.drawGridCell();
            
        end

        function createsquare(app,px,py,tamanho,d)
            for i=-tamanho:tamanho
                for j=-tamanho:tamanho
                    if (((px+i)<=app.Nc && (px+i)>0) && ((py+j)<=app.Nc && (py+j)>0))
                        app.grid(px+i,py+j)=d;
                    end
                end
            end
            app.drawGridCell();
            
        end

        function createRetaV(app,px,py,tamanho,d)
            for i=-tamanho:tamanho
                if (((px+i)<=app.Nc && (px+i)>0))
                    app.grid(px+i,py)=d;
                end
            end
            app.drawGridCell();
            
        end

        function createRetaH(app,px,py,tamanho,d)
            for i=-tamanho:tamanho
                if (((py+i)<=app.Nc && (py+i)>0))
                    app.grid(px,py+i)=d;
                end
            end
            app.drawGridCell();
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Nc = str2double(app.NEditField.Value);
    
            app.grid = zeros(app.Nc);
            app.grid_direction = cell(app.Nc);
            app.thresholds = linspace(0,1,str2double(app.NumTonesEditField.Value));
            A= size(app.thresholds);
            
            app.numTones = A(2);
            app.COLOR_SCALE = uint8([zeros(app.numTones, 2), linspace(255, 160, app.numTones)']);
            app.COLOR_SCALE(1, :) = [255, 255, 255];
            app.COLOR_SCALE = reshape(app.COLOR_SCALE, [app.numTones, 1, 3]);
            app.coloredImage = uint8(zeros(app.Nc, app.Nc, 3));
        
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            app.drawGridCell()
        end

        % Button pushed function: R_ECellsButton
        function R_ECellsButton_2Pushed(app, event)
            Px=str2double(app.PyEditField_3.Value);
            Py=str2double(app.PxEditField_3.Value);
            if Px>0 && Py>0
            densidade=str2double(app.DensidadeEditField.Value);
            app.grid(Px,Py)=densidade;
            app.drawGridCell();
            else
                app.RogerioFalas.Text="Naooooooo voce nao pode colocar uma posiçao menor que 1.";
            end
            
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.Nc = str2double(app.NEditField.Value);
    
            app.grid = zeros(app.Nc);
            app.grid_direction = cell(app.Nc);
            app.thresholds = linspace(0,1,str2double(app.NumTonesEditField.Value));
            A= size(app.thresholds);
            
            app.numTones = A(2);
            app.COLOR_SCALE = uint8([zeros(app.numTones, 2), linspace(255, 160, app.numTones)']);
            app.COLOR_SCALE(1, :) = [255, 255, 255];
            app.COLOR_SCALE = reshape(app.COLOR_SCALE, [app.numTones, 1, 3]);
            app.coloredImage = uint8(zeros(app.Nc, app.Nc, 3));
        
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            app.drawGridCell()


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                
            tt = str2double(app.TimeEditField.Value);
            for i=0:tt
                
                hold(app.Graph,"off");
                [app.grid,app.grid_direction]=app.update(app.grid,app.grid_direction);
                app.drawGridCell()
                hold(app.Graph,"on");
                if (app.VetoresSwitch.Value=="On")
                    U = zeros(size(app.grid)); % Componente vertical das setas
                    V = zeros(size(app.grid)); % Componente horizontal das setas
                    for y = 1:app.Nc
                        for x = 1:app.Nc
                            U(y, x) = app.grid_direction{y, x}(1);
                            V(y, x) = app.grid_direction{y, x}(2);
                        end
                    end
                    quiver(app.Graph,1:app.Nc, 1:app.Nc, V, U, 'AutoScale', 'off');
                end

                drawnow;
            end


        end

        % Button pushed function: R_EBlocksButton
        function R_EBlocksButton_2Pushed(app, event)
            Px=str2double(app.PyEditField_4.Value);
            Py=str2double(app.PxEditField_4.Value);
            if Px>0 && Py>0
            app.grid(Px,Py)=-1;
            app.drawGridCell();
            else
                app.RogerioFalas.Text="Naooooooo voce nao pode colocar uma posiçao menor que 1.";
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            Px=str2double(app.PyEditField_5.Value);
            Py=str2double(app.PxEditField_5.Value);
            if Px>0 && Py>0
            app.grid(Px,Py)=0;
            app.drawGridCell();
            else
                app.RogerioFalas.Text="Naooooooo voce nao pode colocar uma posiçao menor que 1.";
            end
        end

        % Button pushed function: CreateBlockButton
        function CreateBlockButtonPushed(app, event)
            Px=str2double(app.PxC_Eblock.Value);
            Py=str2double(app.PyC_Eblock.Value);
            if Px>0 && Py>0
                tamanho = str2double(app.tamanhoEditField.Value);
                type = app.TypeDropDown.Value;
                figur = app.Switch.Value;
                d = 1;
                if figur=="Bloco"
                    d=-1;
                end
                
                switch(type)
                    case 'Circulo'
                        app.createCircle(Px,Py,tamanho,d)
                    case 'Quadrado'
                        app.createsquare(Px,Py,tamanho,d)
                    case 'RetaV'
                        app.createRetaV(Px,Py,tamanho,d)
                    case 'RetaH'
                        app.createRetaH(Px,Py,tamanho,d)
                end
            else
                app.RogerioFalas.Text="Naooooooo voce nao pode colocar uma posiçao menor que 1.";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            n = size(app.frasesDoRogerio);
            k = round(rand*n(1));
            if k ==0
                k=1;
            end

            app.RogerioFalas.Text=app.frasesDoRogerio(k);
        end

        % Button pushed function: Modelo1Button
        function Modelo1ButtonPushed(app, event)
                N = 32; % Tamanho da matriz
                n = 10; % Tamanho do quadrado
                
                % Criar a matriz NxN com zeros
                matriz = zeros(N);
                app.grid_direction=cell(N);
                for y = 1:app.Nc
                    for x = 1:app.Nc
                        app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                    end
                end
                
                % Preencher as bordas da matriz com -1
                matriz(1, :) = -1;
                matriz(end, :) = -1;
                matriz(:, 1) = -1;
                matriz(:, end) = -1;
                
                % Calcular os índices do quadrado central
                startRow = floor((N - n) / 2) + 1;
                endRow = startRow + n - 1;
                startCol = floor((N - n) / 2) + 1;
                endCol = startCol + n - 1;
                
                % Preencher as bordas do quadrado central oco com o valor desejado, por exemplo, 1
                %matriz(startRow, startCol:endCol) = -1; % Borda superior
                matriz(endRow, startCol:endCol) = -1;   % Borda inferior
                matriz(startRow:endRow, startCol) = -1; % Borda esquerda
                matriz(startRow:endRow, endCol) = -1;   % Borda direita
                
                matriz(endRow-1,round((startCol+endCol)/2))=10;
                app.grid=matriz;
                app.drawGridCell();
                
        end

        % Button pushed function: Modelo2Button
        function Modelo2ButtonPushed(app, event)
            N = 32; % Tamanho da matriz
            
            % Criar a matriz NxN com zeros
            matriz = zeros(N);
            app.grid_direction=cell(N);
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            
            % Preencher as bordas da matriz com -1
            matriz(1, :) = -1;
            matriz(end, :) = -1;
            matriz(:, 1) = -1;
            matriz(:, end) = -1;
            
            for i=1:N
                matriz(i,i)=-1;
            end
            matriz(3,4)=10;
            app.grid=matriz;
            app.drawGridCell();

        end

        % Button pushed function: Modelo3Button
        function Modelo3ButtonPushed(app, event)
            N = 32; % Tamanho da matriz
            n = 15; % Tamanho do quadrado
            
            % Criar a matriz NxN com zeros
            matriz = zeros(N);
            app.grid_direction=cell(N);
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            
            % Preencher as bordas da matriz com -1
            matriz(1, :) = -1;
            matriz(end, :) = -1;
            matriz(:, 1) = -1;
            matriz(:, end) = -1;
            
            startRow = floor((N - n) / 2) +3;
            endRow = startRow + n +5;
            startCol = floor((N - n) / 2) + 1;
            endCol = startCol + n - 1;
            
            matriz(endRow, startCol:endCol) = -1;   % Borda inferior
            matriz(startRow:endRow, startCol) = -1; % Borda esquerda
            matriz(startRow:endRow, endCol) = -1;   % Borda direita
            
            
            matriz(3,2:9)=-1;
            matriz(4,2)=10;
            matriz(5,2:9)=-1;
            app.grid=matriz;
            app.drawGridCell();
        end

        % Button pushed function: Modelo4Button
        function Modelo4ButtonPushed(app, event)
           N = 32; % Tamanho da matriz
            
            matriz = zeros(N);
            app.grid_direction = cell(N);
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            
            % Preencher as bordas da matriz com -1
            matriz(1, :) = -1;
            matriz(end, :) = -1;
            matriz(:, 1) = -1;
            matriz(:, end) = -1;
            
            % Criar um "X" no centro
            for i = 1:N
                matriz(i, i) = -1;
                matriz(i, N - i + 1) = -1;
            end
            
            % Colocar um ponto de líquido no centro
            matriz(floor(N / 2), floor(N / 2)) = 10;
            
            app.grid = matriz;
            app.drawGridCell(); 
        end

        % Button pushed function: Modelo5Button
        function Modelo5ButtonPushed(app, event)
            N = 32; % Tamanho da matriz
    
            % Criar a matriz NxN com zeros
            matriz = zeros(N);
            app.grid_direction = cell(N);
            for y = 1:app.Nc
                for x = 1:app.Nc
                    app.grid_direction{y, x} = [0, 0]; % Sem direção inicial
                end
            end
            
            % Preencher as bordas da matriz com -1
            matriz(1, :) = -1;
            matriz(end, :) = -1;
            matriz(:, 1) = -1;
            matriz(:, end) = -1;
            
            % Criar ladeiras diagonais
            for i = 1:N
                if mod(i, 2) == 0
                    matriz(i, i) = -1;
                end
                if mod(N - i + 1, 2) == 0
                    matriz(i, N - i + 1) = -1;
                end
            end
            
            % Colocar um ponto de líquido na interseção
            matriz(floor(N / 2), floor(N / 2)) = 10;
            
            app.grid = matriz;
            app.drawGridCell();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout7 = uigridlayout(app.UIFigure);
            app.GraphPanel = uipanel(app.GridLayout7);
            app.Panel = uipanel(app.GraphPanel);
            app.Graph = uiaxes(app.Panel);
            app.Graph.Visible = 'off';
            app.Button = uibutton(app.GraphPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Rogerio.png');
            app.Button.Text = '';
            app.RogerioPanel = uipanel(app.GraphPanel);
            app.RogerioPanel.Title = 'Rogerio';
            app.RogerioFalas = uilabel(app.RogerioPanel);
            app.RogerioFalas.Text = 'Ola';
            app.ButtonPanel = uipanel(app.GridLayout7);
            app.TabGroupButtons = uitabgroup(app.ButtonPanel);
            app.ReMakeGrid = uitab(app.TabGroupButtons);
            app.ReMakeGrid.Title = 'ReMakeGrid';
            app.GridLayout4 = uigridlayout(app.ReMakeGrid);
            app.CreateButton = uibutton(app.GridLayout4, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Icon = fullfile(pathToMLAPP, 'wrench.png');
            app.CreateButton.Text = 'Create';
            app.PlayButton = uibutton(app.GridLayout4, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'button.png');
            app.PlayButton.Text = 'Play';
            app.NEditField = uieditfield(app.GridLayout4, 'text');
            app.NEditField.Value = '32';
            app.NumTonesEditField = uieditfield(app.GridLayout4, 'text');
            app.NumTonesEditField.Value = '10';
            app.TimeEditField = uieditfield(app.GridLayout4, 'text');
            app.TimeEditField.Value = '1000';
            app.TempoLabel = uilabel(app.GridLayout4);
            app.TempoLabel.Text = 'Tempo';
            app.SizeLabel = uilabel(app.GridLayout4);
            app.SizeLabel.Text = 'Size';
            app.NumerodeTonsLabel = uilabel(app.GridLayout4);
            app.NumerodeTonsLabel.Text = 'Numero de Tons';
            app.CRIAAOLabel = uilabel(app.GridLayout4);
            app.CRIAAOLabel.Text = 'CRIAÇAO';
            app.DRAWDropDownLabel = uilabel(app.GridLayout4);
            app.DRAWDropDownLabel.Text = 'DRAW';
            app.DRAWDropDown = uidropdown(app.GridLayout4);
            app.DRAWDropDown.Items = {'imagesc', 'contourf', 'pcolor', 'contour'};
            app.DRAWDropDown.Value = 'imagesc';
            app.VetoresSwitchLabel = uilabel(app.GridLayout4);
            app.VetoresSwitchLabel.Text = 'Vetores?';
            app.VetoresSwitch = uiswitch(app.GridLayout4, 'slider');
            app.C_ECell = uitab(app.TabGroupButtons);
            app.C_ECell.Title = 'C_ECell';
            app.GridLayout5 = uigridlayout(app.C_ECell);
            app.R_ECellsButton = uibutton(app.GridLayout5, 'push');
            app.R_ECellsButton.ButtonPushedFcn = createCallbackFcn(app, @R_ECellsButton_2Pushed, true);
            app.R_ECellsButton.Icon = fullfile(pathToMLAPP, 'wrench.png');
            app.R_ECellsButton.Text = 'R_ECells';
            app.PyEditField_3 = uieditfield(app.GridLayout5, 'text');
            app.PyEditField_3.Value = '1';
            app.PxEditField_3 = uieditfield(app.GridLayout5, 'text');
            app.PxEditField_3.Value = '1';
            app.DensidadeEditField = uieditfield(app.GridLayout5, 'text');
            app.DensidadeEditField.Value = '0';
            app.R_EBlocksButton = uibutton(app.GridLayout5, 'push');
            app.R_EBlocksButton.ButtonPushedFcn = createCallbackFcn(app, @R_EBlocksButton_2Pushed, true);
            app.R_EBlocksButton.Icon = fullfile(pathToMLAPP, 'wrench.png');
            app.R_EBlocksButton.Text = 'R_EBlocks';
            app.PyEditField_4 = uieditfield(app.GridLayout5, 'text');
            app.PyEditField_4.Value = '1';
            app.PxEditField_4 = uieditfield(app.GridLayout5, 'text');
            app.PxEditField_4.Value = '1';
            app.RemoveButton = uibutton(app.GridLayout5, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Icon = fullfile(pathToMLAPP, 'wrench.png');
            app.RemoveButton.Text = 'Remove';
            app.PyEditField_5 = uieditfield(app.GridLayout5, 'text');
            app.PyEditField_5.Value = '1';
            app.PxEditField_5 = uieditfield(app.GridLayout5, 'text');
            app.PxEditField_5.Value = '1';
            app.PyLabel = uilabel(app.GridLayout5);
            app.PyLabel.Text = 'Py';
            app.PxLabel = uilabel(app.GridLayout5);
            app.PxLabel.Text = 'Px';
            app.DensidadeLabel = uilabel(app.GridLayout5);
            app.DensidadeLabel.Text = 'Densidade';
            app.PyLabel_2 = uilabel(app.GridLayout5);
            app.PyLabel_2.Text = 'Py';
            app.PxLabel_2 = uilabel(app.GridLayout5);
            app.PxLabel_2.Text = 'Px';
            app.PyLabel_3 = uilabel(app.GridLayout5);
            app.PyLabel_3.Text = 'Py';
            app.PxLabel_3 = uilabel(app.GridLayout5);
            app.PxLabel_3.Text = 'Px';
            app.RemoverLabel = uilabel(app.GridLayout5);
            app.RemoverLabel.Text = 'Remover';
            app.AdicionarblocoLabel = uilabel(app.GridLayout5);
            app.AdicionarblocoLabel.Text = 'Adicionar bloco';
            app.AdicionarLiquidoLabel = uilabel(app.GridLayout5);
            app.AdicionarLiquidoLabel.Text = 'Adicionar Liquido';
            app.C_EBlocks = uitab(app.TabGroupButtons);
            app.C_EBlocks.Title = 'C_EBlocks';
            app.GridLayout6 = uigridlayout(app.C_EBlocks);
            app.Switch = uiswitch(app.GridLayout6, 'slider');
            app.Switch.Items = {'Water', 'Bloco'};
            app.Switch.Value = 'Water';
            app.CreateBlockButton = uibutton(app.GridLayout6, 'push');
            app.CreateBlockButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBlockButtonPushed, true);
            app.CreateBlockButton.Icon = fullfile(pathToMLAPP, 'wrench.png');
            app.CreateBlockButton.Text = 'CreateBlock';
            app.PyEditField_6Label = uilabel(app.GridLayout6);
            app.PyEditField_6Label.Text = 'Py';
            app.PxC_Eblock = uieditfield(app.GridLayout6, 'text');
            app.PxC_Eblock.Value = '1';
            app.PxLabel_4 = uilabel(app.GridLayout6);
            app.PxLabel_4.Text = 'Px';
            app.PyC_Eblock = uieditfield(app.GridLayout6, 'text');
            app.PyC_Eblock.Value = '1';
            app.tamanhoEditFieldLabel = uilabel(app.GridLayout6);
            app.tamanhoEditFieldLabel.Text = 'tamanho';
            app.tamanhoEditField = uieditfield(app.GridLayout6, 'text');
            app.tamanhoEditField.Value = '0';
            app.TypeDropDownLabel = uilabel(app.GridLayout6);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout6);
            app.TypeDropDown.Items = {'Quadrado', 'RetaV', 'RetaH', 'Circulo'};
            app.TypeDropDown.Value = 'Quadrado';
            app.C_EBlocks_2 = uitab(app.TabGroupButtons);
            app.C_EBlocks_2.Title = 'C_EBlocks';
            app.GridLayout6_2 = uigridlayout(app.C_EBlocks_2);
            app.Modelo1Button = uibutton(app.GridLayout6_2, 'push');
            app.Modelo1Button.ButtonPushedFcn = createCallbackFcn(app, @Modelo1ButtonPushed, true);
            app.Modelo1Button.Text = 'Modelo 1';
            app.Modelo2Button = uibutton(app.GridLayout6_2, 'push');
            app.Modelo2Button.ButtonPushedFcn = createCallbackFcn(app, @Modelo2ButtonPushed, true);
            app.Modelo2Button.Text = 'Modelo 2';
            app.Modelo3Button = uibutton(app.GridLayout6_2, 'push');
            app.Modelo3Button.ButtonPushedFcn = createCallbackFcn(app, @Modelo3ButtonPushed, true);
            app.Modelo3Button.Text = 'Modelo 3';
            app.Modelo4Button = uibutton(app.GridLayout6_2, 'push');
            app.Modelo4Button.ButtonPushedFcn = createCallbackFcn(app, @Modelo4ButtonPushed, true);
            app.Modelo4Button.Text = 'Modelo 4';
            app.Modelo5Button = uibutton(app.GridLayout6_2, 'push');
            app.Modelo5Button.ButtonPushedFcn = createCallbackFcn(app, @Modelo5ButtonPushed, true);
            app.Modelo5Button.Text = 'Modelo 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluidAutomata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end