classdef FluidNavier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ButtonPanel              matlab.ui.container.Panel
        TabGroupButtons          matlab.ui.container.TabGroup
        WaterSettings            matlab.ui.container.Tab
        GridLayout               matlab.ui.container.GridLayout
        PLAYButton               matlab.ui.control.Button
        TimeEditField            matlab.ui.control.EditField
        TimeEditFieldLabel       matlab.ui.control.Label
        VISCOSITYEditField       matlab.ui.control.EditField
        VISCOSITYEditFieldLabel  matlab.ui.control.Label
        DIFFEditField            matlab.ui.control.EditField
        DIFFEditFieldLabel       matlab.ui.control.Label
        DTEditField              matlab.ui.control.EditField
        DTEditFieldLabel         matlab.ui.control.Label
        SIZEEditField            matlab.ui.control.EditField
        SIZEEditFieldLabel       matlab.ui.control.Label
        FADEONCheckBox           matlab.ui.control.CheckBox
        REDITButton              matlab.ui.control.Button
        C_ECell                  matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        SetCellsButton           matlab.ui.control.Button
        PyEditField              matlab.ui.control.EditField
        PyEditFieldLabel         matlab.ui.control.Label
        PxEditField              matlab.ui.control.EditField
        PxEditFieldLabel         matlab.ui.control.Label
        VyEditField              matlab.ui.control.EditField
        VyEditFieldLabel         matlab.ui.control.Label
        VxEditField              matlab.ui.control.EditField
        VxEditFieldLabel         matlab.ui.control.Label
        RemCellsButton           matlab.ui.control.Button
        C_EBlocks                matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        SyEditField              matlab.ui.control.EditField
        SyEditFieldLabel         matlab.ui.control.Label
        SxEditField              matlab.ui.control.EditField
        SxEditFieldLabel         matlab.ui.control.Label
        AngleEditField           matlab.ui.control.EditField
        AngleEditFieldLabel      matlab.ui.control.Label
        PyEditField_2            matlab.ui.control.EditField
        PyEditField_2Label       matlab.ui.control.Label
        PxEditField_2            matlab.ui.control.EditField
        PxEditField_2Label       matlab.ui.control.Label
        SetBlocksButton          matlab.ui.control.Button
        GraphPanel               matlab.ui.container.Panel
        RogerioPanel             matlab.ui.container.Panel
        RogerioFalas             matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        Graph                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Navier
        N % Tamanho do grid WaterNavier
        fluid % Fluid Navier
        fluidCreate=0; % Indica se o fluido navier foi criado
        time=0; % tempo do loop
        MatrixCube=[]
        rogerioFalas = {
            'Vamos lá, pessoal! Vamos fazer esse fluido fluir!'
            'Tudo pronto para começar? Então, apertem os cintos!'
            'Você está fazendo um ótimo trabalho! Continue assim!'
            'Vamos ver como esse fluido se comporta com esses novos parâmetros.'
            'Boa escolha! Vamos ver os resultados!'
            'Não se esqueça de verificar os parâmetros antes de iniciar.'
            'Estamos quase lá! Só mais alguns ajustes.'
            'Ótimo! Os blocos foram configurados com sucesso.'
            'Você está prestes a ver a magia do fluido em ação!'
            'Vamos adicionar algumas células e ver o que acontece!'
            'Vamos ajustar a viscosidade e ver como o fluido se comporta!'
            'Ótima escolha de parâmetros! Agora, vamos rodar a simulação.'
            'Estamos adicionando densidade. Fique de olho nas mudanças!'
            'Preparando para iniciar a simulação... Tudo pronto?'
            'Observe como a advecção afeta o comportamento do fluido.'
            'Você pode ver como a difusão está suavizando as variações.'
            'Vamos adicionar alguns obstáculos e ver a interação com o fluido.'
            'Ajuste os parâmetros de difusão para ver diferentes padrões de fluido.'
            'Iniciando o passo de tempo... Vamos observar as mudanças.'
            'A densidade está sendo adicionada. Vamos ver o efeito no grid.'
            'A projeção está corrigindo o campo de velocidade. Olhe a incompressibilidade do fluido!'
            'Definindo condições de contorno... Vamos manter o fluido confinado.'
            'A simulação está em andamento! Vamos monitorar os resultados.'
            'Você está prestes a ver como pequenos ajustes podem impactar significativamente o comportamento do fluido.'
            'Vamos rotacionar os obstáculos e observar as novas interações!'
            'Tudo configurado! Hora de ver o fluido em ação.'
            'Vamos ver como o campo de velocidade está evoluindo com essas novas configurações.'
            'Ajustando o intervalo de tempo. Vamos monitorar a precisão da simulação.'
            'Excelente! Os parâmetros estão configurados. Vamos rodar a simulação.'
            'Atenção aos detalhes! Pequenas mudanças podem levar a grandes descobertas.'
        };


    end
    
    methods (Access = private)
        
        function draw(app)
            image=[];
            if size(app.MatrixCube)==size(app.fluid.density)
                image = app.fluid.density+app.MatrixCube;
            else
                image = app.fluid.density;

            end
            imagesc(app.Graph,image);
            colormap(app.Graph,gray);
            
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: REDITButton
        function REDITButtonPushed(app, event)
            visc = str2double(app.VISCOSITYEditField.Value);
            diff =str2double(app.DIFFEditField.Value);
            dt = str2double(app.DTEditField.Value);
            app.N = str2double(app.SIZEEditField.Value);
            app.time = str2double(app.TimeEditField.Value);
            fadeon = app.FADEONCheckBox.Value;
            app.fluid = Fluid(app.N,dt, diff, visc);
            app.fluidCreate=1;
            app.draw()
            
        end

        % Value changed function: SIZEEditField
        function SIZEEditFieldValueChanged(app, event)
            app.N = str2double(app.SIZEEditField.Value);
        end

        % Button pushed function: RemCellsButton
        function RemCellsButtonPushed(app, event)
            if app.fluidCreate==1
            Vx = str2double(app.VxEditField.Value);
            Vy = str2double(app.VyEditField.Value);
            Px = str2double(app.PxEditField.Value);
            Py = str2double(app.PyEditField.Value);
            if Px>0 && Py>0
            app.fluid.addDensity(Px, Py, 0);
            app.fluid.addVelocity(Px, Py, Vx, Vy);
            app.draw()
            end

            end
            
            
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            k=0;
            if app.fluidCreate==1
                for t=0:app.time
	                app.fluid.step();
                    app.draw()
                    drawnow
                
                end
            end
        end

        % Button pushed function: SetBlocksButton
        function SetBlocksButtonPushed(app, event)
            if app.fluidCreate==1
                Px = str2double(app.PxEditField_2.Value);
                Py =str2double(app.PyEditField_2.Value);
                if Px>0 && Py>0
                angle =str2double(app.AngleEditField.Value);
                Sx =str2double(app.SxEditField.Value);
                Sy =str2double(app.SyEditField.Value);
                cube = Square([Px,Py],Sx,Sy,angle);
                app.MatrixCube = 500*cube.draw(app.N); 
                app.fluid.setBlock(app.MatrixCube);
                app.draw()
                end
            end
        end

        % Button pushed function: SetCellsButton
        function SetCellsButtonPushed(app, event)
            if app.fluidCreate==1
            Vx = str2double(app.VxEditField.Value);
            Vy = str2double(app.VyEditField.Value);
            Px = str2double(app.PxEditField.Value);
            Py = str2double(app.PyEditField.Value);
            if Px>0 && Py>0
            app.fluid.addDensity(Px, Py, floor(50+rand*100));
            app.fluid.addVelocity(Px, Py, Vx, Vy);
            app.draw()
            end

            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            n = size(app.rogerioFalas);
            k = round(rand*n(1));
            if k ==0
                k=1;
            end

            app.RogerioFalas.Text=app.rogerioFalas(k);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraphPanel = uipanel(app.UIFigure);
            app.Graph = uiaxes(app.GraphPanel);
            app.Graph.Visible = 'off';
            app.Button = uibutton(app.GraphPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Rogerio.png');
            app.Button.Text = '';
            app.RogerioPanel = uipanel(app.GraphPanel);
            app.RogerioPanel.Title = 'Rogerio';
            app.RogerioFalas = uilabel(app.RogerioPanel);
            app.RogerioFalas.Text = 'Ola';
            app.ButtonPanel = uipanel(app.UIFigure);
            app.TabGroupButtons = uitabgroup(app.ButtonPanel);
            app.WaterSettings = uitab(app.TabGroupButtons);
            app.WaterSettings.Title = 'WaterSettings';
            app.GridLayout = uigridlayout(app.WaterSettings);
            app.REDITButton = uibutton(app.GridLayout, 'push');
            app.REDITButton.ButtonPushedFcn = createCallbackFcn(app, @REDITButtonPushed, true);
            app.REDITButton.Text = 'REDIT';
            app.FADEONCheckBox = uicheckbox(app.GridLayout);
            app.FADEONCheckBox.Text = 'FADEON';
            app.SIZEEditFieldLabel = uilabel(app.GridLayout);
            app.SIZEEditFieldLabel.Text = 'SIZE  ';
            app.SIZEEditField = uieditfield(app.GridLayout, 'text');
            app.SIZEEditField.InputType = 'digits';
            app.SIZEEditField.ValueChangedFcn = createCallbackFcn(app, @SIZEEditFieldValueChanged, true);
            app.SIZEEditField.Value = '64';
            app.DTEditFieldLabel = uilabel(app.GridLayout);
            app.DTEditFieldLabel.Text = 'DT  ';
            app.DTEditField = uieditfield(app.GridLayout, 'text');
            app.DTEditField.Value = '1.5';
            app.DIFFEditFieldLabel = uilabel(app.GridLayout);
            app.DIFFEditFieldLabel.Text = 'DIFF ';
            app.DIFFEditField = uieditfield(app.GridLayout, 'text');
            app.DIFFEditField.Value = '0';
            app.VISCOSITYEditFieldLabel = uilabel(app.GridLayout);
            app.VISCOSITYEditFieldLabel.Text = 'VISCOSITY';
            app.VISCOSITYEditField = uieditfield(app.GridLayout, 'text');
            app.VISCOSITYEditField.Value = '0';
            app.TimeEditFieldLabel = uilabel(app.GridLayout);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.GridLayout, 'text');
            app.TimeEditField.InputType = 'digits';
            app.TimeEditField.Value = '100';
            app.PLAYButton = uibutton(app.GridLayout, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.C_ECell = uitab(app.TabGroupButtons);
            app.C_ECell.Title = 'C_ECell';
            app.GridLayout2 = uigridlayout(app.C_ECell);
            app.RemCellsButton = uibutton(app.GridLayout2, 'push');
            app.RemCellsButton.ButtonPushedFcn = createCallbackFcn(app, @RemCellsButtonPushed, true);
            app.RemCellsButton.Text = 'RemCells';
            app.VxEditFieldLabel = uilabel(app.GridLayout2);
            app.VxEditFieldLabel.Text = 'Vx';
            app.VxEditField = uieditfield(app.GridLayout2, 'text');
            app.VxEditField.InputType = 'digits';
            app.VxEditField.Value = '0';
            app.VyEditFieldLabel = uilabel(app.GridLayout2);
            app.VyEditFieldLabel.Text = 'Vy';
            app.VyEditField = uieditfield(app.GridLayout2, 'text');
            app.VyEditField.InputType = 'digits';
            app.VyEditField.Value = '0';
            app.PxEditFieldLabel = uilabel(app.GridLayout2);
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.GridLayout2, 'text');
            app.PxEditField.CharacterLimits = [1 Inf];
            app.PxEditField.InputType = 'digits';
            app.PxEditField.Value = '1';
            app.PyEditFieldLabel = uilabel(app.GridLayout2);
            app.PyEditFieldLabel.Text = 'Py';
            app.PyEditField = uieditfield(app.GridLayout2, 'text');
            app.PyEditField.CharacterLimits = [1 Inf];
            app.PyEditField.InputType = 'digits';
            app.PyEditField.Value = '1';
            app.SetCellsButton = uibutton(app.GridLayout2, 'push');
            app.SetCellsButton.ButtonPushedFcn = createCallbackFcn(app, @SetCellsButtonPushed, true);
            app.SetCellsButton.Text = 'SetCells';
            app.C_EBlocks = uitab(app.TabGroupButtons);
            app.C_EBlocks.Title = 'C_EBlocks';
            app.GridLayout3 = uigridlayout(app.C_EBlocks);
            app.SetBlocksButton = uibutton(app.GridLayout3, 'push');
            app.SetBlocksButton.ButtonPushedFcn = createCallbackFcn(app, @SetBlocksButtonPushed, true);
            app.SetBlocksButton.Text = 'SetBlocks';
            app.PxEditField_2Label = uilabel(app.GridLayout3);
            app.PxEditField_2Label.Text = 'Px';
            app.PxEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.PxEditField_2.CharacterLimits = [1 Inf];
            app.PxEditField_2.InputType = 'digits';
            app.PxEditField_2.Value = '1';
            app.PyEditField_2Label = uilabel(app.GridLayout3);
            app.PyEditField_2Label.Text = 'Py';
            app.PyEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.PyEditField_2.CharacterLimits = [1 Inf];
            app.PyEditField_2.InputType = 'digits';
            app.PyEditField_2.Value = '1';
            app.AngleEditFieldLabel = uilabel(app.GridLayout3);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.GridLayout3, 'text');
            app.AngleEditField.InputType = 'digits';
            app.AngleEditField.Value = '0';
            app.SxEditFieldLabel = uilabel(app.GridLayout3);
            app.SxEditFieldLabel.Text = 'Sx';
            app.SxEditField = uieditfield(app.GridLayout3, 'text');
            app.SxEditField.InputType = 'digits';
            app.SxEditField.Value = '0';
            app.SyEditFieldLabel = uilabel(app.GridLayout3);
            app.SyEditFieldLabel.Text = 'Sy';
            app.SyEditField = uieditfield(app.GridLayout3, 'text');
            app.SyEditField.InputType = 'digits';
            app.SyEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluidNavier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end